classdef Coursework4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EdgeDetectionOperatorsPanel    matlab.ui.container.Panel
        RobertsUIAxes                  matlab.ui.control.UIAxes
        EdgeUIAxes                     matlab.ui.control.UIAxes
        LogUIAxes                      matlab.ui.control.UIAxes
        CannyUIAxes                    matlab.ui.control.UIAxes
        PrewittUIAxes                  matlab.ui.control.UIAxes
        SobelUIAxes                    matlab.ui.control.UIAxes
        OriginalImagePanel             matlab.ui.container.Panel
        MainUIAxes                     matlab.ui.control.UIAxes
        OriginalUIAxes                 matlab.ui.control.UIAxes
        ControlsPanel                  matlab.ui.container.Panel
        UploadanImageButton            matlab.ui.control.Button
        SelectoneofthepreloadedimagesLabel  matlab.ui.control.Label
        SelectoneofthepreloadedimagesDropDown  matlab.ui.control.DropDown
        SelectoneoftheedgecolorsLabel  matlab.ui.control.Label
        SelectoneoftheedgecolorsDropDown  matlab.ui.control.DropDown
        orLabel                        matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Image_2                        matlab.ui.control.Image
    end

    
    methods (Access = public)


        function grayimage = grayimg(app, im)                       
            [row, col, dim] = size (im)
            if (dim > 1)
	       grayimage = rgb2gray(im);
            else
               grayimage = im
            end           
        end
        
        function myedgedetection = myedge(app, im)
            [row col] = size(im)
            for i=1:row-1
                for j=1:col-1
                    myedgedetection(i,j) = abs(im(i,j)-im(i+1,j+1))+ abs(im(i,j+1)-im(i+1,j));
                end
            end
        end   
        
        
        function edgedetections = alledges(app, im)
            myedges = myedge(app,im);
            robert = edge(im,'roberts');
            sobel = edge(im,'sobel');
            log = edge(im,'log');
            canny = edge(im,'canny');
            prewitt = edge(im,'prewitt');   
            
            colorvalue = app.SelectoneoftheedgecolorsDropDown.Value;
            if colorvalue == "Black and White"
                imshow(myedges, 'parent', app.EdgeUIAxes);
                imshow(robert,'parent', app.RobertsUIAxes);
                imshow(sobel, 'parent', app.SobelUIAxes);
                imshow(canny, 'parent', app.CannyUIAxes);
                imshow(prewitt, 'parent', app.PrewittUIAxes);
                imshow(log, 'parent', app.LogUIAxes);
                imshow(im, 'parent', app.MainUIAxes);
            elseif colorvalue == "Red"
                imshow(myedges,'ColorMap', [1 1 1; 1 0 0], 'parent', app.EdgeUIAxes);
                imshow(robert, 'ColorMap', [1 1 1; 1 0 0],'parent', app.RobertsUIAxes);
                imshow(sobel,'ColorMap', [1 1 1; 1 0 0], 'parent', app.SobelUIAxes);
                imshow(canny,'ColorMap', [1 1 1; 1 0 0], 'parent', app.CannyUIAxes);
                imshow(prewitt,'ColorMap', [1 1 1; 1 0 0], 'parent', app.PrewittUIAxes);
                imshow(log,'ColorMap', [1 1 1; 1 0 0], 'parent', app.LogUIAxes);
                imshow(im, 'parent', app.MainUIAxes);
            elseif colorvalue == "Green"
                imshow(myedges,'ColorMap', [1 1 1; 0 1 0], 'parent', app.EdgeUIAxes);
                imshow(robert, 'ColorMap', [1 1 1; 0 1 0],'parent', app.RobertsUIAxes);
                imshow(sobel,'ColorMap', [1 1 1; 0 1 0], 'parent', app.SobelUIAxes);
                imshow(canny,'ColorMap', [1 1 1; 0 1 0], 'parent', app.CannyUIAxes);
                imshow(prewitt,'ColorMap', [1 1 1; 0 1 0], 'parent', app.PrewittUIAxes);
                imshow(log,'ColorMap', [1 1 1; 0 1 0], 'parent', app.LogUIAxes);
                imshow(im, 'parent', app.MainUIAxes);
        
            elseif colorvalue == "Blue"
                imshow(myedges,'ColorMap', [1 1 1; 0 0 1], 'parent', app.EdgeUIAxes);
                imshow(robert,'ColorMap', [1 1 1; 0 0 1],'parent', app.RobertsUIAxes);
                imshow(sobel,'ColorMap', [1 1 1; 0 0 1], 'parent', app.SobelUIAxes);
                imshow(canny,'ColorMap', [1 1 1; 0 0 1], 'parent', app.CannyUIAxes);
                imshow(prewitt,'ColorMap', [1 1 1; 0 0 1], 'parent', app.PrewittUIAxes);
                imshow(log,'ColorMap', [1 1 1; 0 0 1], 'parent', app.LogUIAxes);
                imshow(im, 'parent', app.MainUIAxes);

            else 
                imshow(myedges, 'parent', app.EdgeUIAxes);
                imshow(robert,'parent', app.RobertsUIAxes);
                imshow(sobel, 'parent', app.SobelUIAxes);
                imshow(canny, 'parent', app.CannyUIAxes);
                imshow(prewitt, 'parent', app.PrewittUIAxes);
                imshow(log, 'parent', app.LogUIAxes);
                imshow(im, 'parent', app.MainUIAxes);
            end            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.OriginalUIAxes,'off')
            axis(app.MainUIAxes,'off')
            axis(app.EdgeUIAxes,'off')
            axis(app.LogUIAxes,'off')
            axis(app.SobelUIAxes,'off')
            axis(app.RobertsUIAxes,'off')
            axis(app.CannyUIAxes,'off')
            axis(app.PrewittUIAxes,'off')
            originalimage = imread('cameraman.tif')
            imshow(originalimage, 'parent', app.OriginalUIAxes);
            originalimage = grayimg(app, originalimage)
            alledges(app, originalimage)

        end

        % Value changed function: 
        % SelectoneofthepreloadedimagesDropDown
        function SelectoneofthepreloadedimagesDropDownValueChanged(app, event)
            value = app.SelectoneofthepreloadedimagesDropDown.Value;
            if value == "Cameraman"
                originalimage = imread('cameraman.tif')
                imshow(originalimage, 'parent', app.OriginalUIAxes);
                originalimage = grayimg(app, originalimage)
                alledges(app, originalimage)
            elseif value == "Flamingos"
                originalimage = imread('flamingos.jpg')
                imshow(originalimage, 'parent', app.OriginalUIAxes);
                originalimage = grayimg(app, originalimage)
                alledges(app, originalimage)
            elseif value == "Kids"
                originalimage = imread('kids.tif')
                imshow(originalimage, 'parent', app.OriginalUIAxes);
                originalimage = grayimg(app, originalimage)
                alledges(app, originalimage)
            elseif value == "Ilama"
                originalimage = imread('llama.jpg')
                imshow(originalimage, 'parent', app.OriginalUIAxes);
                originalimage = grayimg(app, originalimage)
                alledges(app, originalimage)            
            elseif value == "Moon"
                originalimage = imread('moon.tif')
                imshow(originalimage, 'parent', app.OriginalUIAxes);
                originalimage = grayimg(app, originalimage)
                alledges(app, originalimage)  
            elseif value == "Peacock"
                originalimage = imread('peacock.jpg')
                imshow(originalimage, 'parent', app.OriginalUIAxes);
                originalimage = grayimg(app, originalimage)
                alledges(app, originalimage)
            elseif value == "Peppers"
                originalimage = imread('peppers.png')
                imshow(originalimage, 'parent', app.OriginalUIAxes);
                originalimage = grayimg(app, originalimage)
                alledges(app, originalimage)
            elseif value == "Rice"
                originalimage = imread('rice.png')
                imshow(originalimage, 'parent', app.OriginalUIAxes);
                originalimage = grayimg(app, originalimage)
                alledges(app, originalimage)
            else
                originalimage = imread('cameraman.tif')
                imshow(originalimage, 'parent', app.OriginalUIAxes);
                originalimage = grayimg(app, originalimage)
                alledges(app, originalimage)
            end
        end

        % Button pushed function: UploadanImageButton
        function UploadanImageButtonPushed(app, event)
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Upload Image');
            uploadimage = fullfile(path, file);
            originalimage = imread(uploadimage)
            imshow(originalimage, 'parent', app.OriginalUIAxes);
            originalimage = grayimg(app, originalimage)
            alledges(app, originalimage)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EdgeDetectionOperatorsPanel = uipanel(app.UIFigure);
            app.EdgeDetectionOperatorsPanel.Title = 'Edge Detection Operators';
            app.RobertsUIAxes = uiaxes(app.EdgeDetectionOperatorsPanel);
            title(app.RobertsUIAxes, 'Roberts Edge Detection')
            xlabel(app.RobertsUIAxes, '')
            ylabel(app.RobertsUIAxes, '')
            app.EdgeUIAxes = uiaxes(app.EdgeDetectionOperatorsPanel);
            title(app.EdgeUIAxes, 'My Edge Detection')
            xlabel(app.EdgeUIAxes, '')
            ylabel(app.EdgeUIAxes, '')
            app.LogUIAxes = uiaxes(app.EdgeDetectionOperatorsPanel);
            title(app.LogUIAxes, 'Laplacian of Gaussian Edge Detection')
            xlabel(app.LogUIAxes, '')
            ylabel(app.LogUIAxes, '')
            app.CannyUIAxes = uiaxes(app.EdgeDetectionOperatorsPanel);
            title(app.CannyUIAxes, 'Canny Edge Detection')
            xlabel(app.CannyUIAxes, '')
            ylabel(app.CannyUIAxes, '')
            app.PrewittUIAxes = uiaxes(app.EdgeDetectionOperatorsPanel);
            title(app.PrewittUIAxes, 'Prewitt Edge Detection')
            xlabel(app.PrewittUIAxes, '')
            ylabel(app.PrewittUIAxes, '')
            app.SobelUIAxes = uiaxes(app.EdgeDetectionOperatorsPanel);
            title(app.SobelUIAxes, 'Sobel Edge Detection')
            xlabel(app.SobelUIAxes, '')
            ylabel(app.SobelUIAxes, '')
            app.OriginalImagePanel = uipanel(app.UIFigure);
            app.OriginalImagePanel.Title = 'Original Image';
            app.MainUIAxes = uiaxes(app.OriginalImagePanel);
            title(app.MainUIAxes, 'Original Image [Gray]')
            xlabel(app.MainUIAxes, '')
            ylabel(app.MainUIAxes, '')
            app.OriginalUIAxes = uiaxes(app.OriginalImagePanel);
            title(app.OriginalUIAxes, 'Original Image')
            xlabel(app.OriginalUIAxes, '')
            ylabel(app.OriginalUIAxes, '')
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.UploadanImageButton = uibutton(app.ControlsPanel, 'push');
            app.UploadanImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadanImageButtonPushed, true);
            app.UploadanImageButton.Text = 'Upload an Image';
            app.SelectoneofthepreloadedimagesLabel = uilabel(app.ControlsPanel);
            app.SelectoneofthepreloadedimagesLabel.Text = 'Select one of the preloaded images';
            app.SelectoneofthepreloadedimagesDropDown = uidropdown(app.ControlsPanel);
            app.SelectoneofthepreloadedimagesDropDown.Items = {'Cameraman', 'Flamingos', 'Kids', 'Ilama', 'Moon', 'Peacock', 'Peppers', 'Rice'};
            app.SelectoneofthepreloadedimagesDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectoneofthepreloadedimagesDropDownValueChanged, true);
            app.SelectoneofthepreloadedimagesDropDown.Value = 'Cameraman';
            app.SelectoneoftheedgecolorsLabel = uilabel(app.ControlsPanel);
            app.SelectoneoftheedgecolorsLabel.Text = 'Select one of the edge colors';
            app.SelectoneoftheedgecolorsDropDown = uidropdown(app.ControlsPanel);
            app.SelectoneoftheedgecolorsDropDown.Items = {'Black and White', 'Red', 'Green', 'Blue'};
            app.SelectoneoftheedgecolorsDropDown.Value = 'Black and White';
            app.orLabel = uilabel(app.ControlsPanel);
            app.orLabel.Text = 'or';
            app.Image = uiimage(app.ControlsPanel);
            app.Image.ImageSource = '1.png';
            app.Image_2 = uiimage(app.ControlsPanel);
            app.Image_2.ImageSource = '2.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Coursework4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end